classdef Interfaz_Grafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        PrincipalTab               matlab.ui.container.Tab
        MiniProyectobalancnLabel   matlab.ui.control.Label
        EstadoActualButton         matlab.ui.control.Button
        RecopilacindedatosButton   matlab.ui.control.Button
        VariacindeparmetrosButton  matlab.ui.control.Button
        EstadoTab                  matlab.ui.container.Tab
        EstadoActualLabel          matlab.ui.control.Label
        RegresarButton             matlab.ui.control.Button
        ValorAngulo                matlab.ui.control.Label
        oLabel                     matlab.ui.control.Label
        AnguloLabel                matlab.ui.control.Label
        ValorAngulo_2              matlab.ui.control.Label
        oLabel_2                   matlab.ui.control.Label
        AnguloLabel_2              matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        GyroscopioLabel            matlab.ui.control.Label
        AcelermetroLabel           matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        Gx                         matlab.ui.control.Label
        Gy                         matlab.ui.control.Label
        Gz                         matlab.ui.control.Label
        Ax                         matlab.ui.control.Label
        Ay                         matlab.ui.control.Label
        Az                         matlab.ui.control.Label
        DatosTab                   matlab.ui.container.Tab
        DatosLabel                 matlab.ui.control.Label
        RegresarButton_2           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        DatosagraficarSliderLabel  matlab.ui.control.Label
        N                          matlab.ui.control.Slider
        reset                      matlab.ui.control.Button
        ParametrosTab              matlab.ui.container.Tab
        ParametrosdelSistemaLabel  matlab.ui.control.Label
        RegresarButton_3           matlab.ui.control.Button
    end

    
    properties (Access = private)
        s % Description
    end
    
    methods (Access = private)
        
        function results = key(app,a,b)
            if (read(app.s,1,'uint8')==a)
                if (read(app.s,1,'uint8')==b)
                    if (read(app.s,1,'uint8')==b)
                        results = 1;
                    else
                        results = 0;
                    end
                else
                    results = 0;
                end
            else
                results = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Datos
            Datos = zeros(1,100);
            app.s = serialport('COM3',115200);
            flush(app.s);
        end

        % Button pushed function: EstadoActualButton
        function EstadoActualButtonPushed(app, event)
            global detenerEstado
            detenerEstado = 0;
            app.TabGroup.SelectedTab = app.EstadoTab;
            while(detenerEstado==0)
                write(app.s,4,'uint8');
                pause(0.01);
                if (app.s.NumBytesAvailable)>16
                    if (key(app,10,3)==1)
                        app.ValorAngulo.Text = string(read(app.s,1,'int16'));
                        app.Ax.Text = string(read(app.s,1,'uint16'));
                        app.Ay.Text = string(read(app.s,1,'uint16'));
                        app.Az.Text = string(read(app.s,1,'uint16'));
                        app.Gx.Text = string(read(app.s,1,'uint16'));
                        app.Gy.Text = string(read(app.s,1,'uint16'));
                        app.Gz.Text = string(read(app.s,1,'uint16'));
                    end
                end
                
            end
        end

        % Button pushed function: RecopilacindedatosButton
        function RecopilacindedatosButtonPushed(app, event)
            global detenerDatos Datos 
            detenerDatos = 0;
            app.TabGroup.SelectedTab = app.DatosTab;
            while (detenerDatos==0)
                write(app.s,2,'uint8')
                pause(0.01);
                if (app.s.NumBytesAvailable)>4
                    if (key(app,2,4)==1)
                        Datos = circshift(Datos,-1);
                        Datos(100) = read(app.s,1,'int16');
                    end
                end
                plot(app.UIAxes,[1:app.N.Value],Datos((101-app.N.Value):100))
            end
        end

        % Button pushed function: VariacindeparmetrosButton
        function VariacindeparmetrosButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParametrosTab;
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            global detenerEstado
            detenerEstado = 1;
            app.TabGroup.SelectedTab = app.PrincipalTab;
        end

        % Button pushed function: RegresarButton_2
        function RegresarButton_2Pushed(app, event)
            
        end

        % Value changed function: N
        function NValueChanged(app, event)
            value = app.N.Value;
            app.UIAxes.XLim = [1,2*value];
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            global Datos
            Datos = zeros(1,100);
            flush(app.s);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrincipalTab = uitab(app.TabGroup);
            app.PrincipalTab.Title = 'Principal';
            app.MiniProyectobalancnLabel = uilabel(app.PrincipalTab);
            app.MiniProyectobalancnLabel.Text = 'Mini-Proyecto: balancín';
            app.EstadoActualButton = uibutton(app.PrincipalTab, 'push');
            app.EstadoActualButton.ButtonPushedFcn = createCallbackFcn(app, @EstadoActualButtonPushed, true);
            app.EstadoActualButton.Text = 'Estado Actual';
            app.RecopilacindedatosButton = uibutton(app.PrincipalTab, 'push');
            app.RecopilacindedatosButton.ButtonPushedFcn = createCallbackFcn(app, @RecopilacindedatosButtonPushed, true);
            app.RecopilacindedatosButton.Text = {'Recopilación'; 'de datos'};
            app.VariacindeparmetrosButton = uibutton(app.PrincipalTab, 'push');
            app.VariacindeparmetrosButton.ButtonPushedFcn = createCallbackFcn(app, @VariacindeparmetrosButtonPushed, true);
            app.VariacindeparmetrosButton.Text = {'Variación de'; 'parámetros'};
            app.EstadoTab = uitab(app.TabGroup);
            app.EstadoTab.Title = 'Estado';
            app.EstadoActualLabel = uilabel(app.EstadoTab);
            app.EstadoActualLabel.Text = 'Estado Actual';
            app.RegresarButton = uibutton(app.EstadoTab, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.ValorAngulo = uilabel(app.EstadoTab);
            app.ValorAngulo.Text = '0';
            app.oLabel = uilabel(app.EstadoTab);
            app.oLabel.Text = 'o';
            app.AnguloLabel = uilabel(app.EstadoTab);
            app.AnguloLabel.Text = 'Ángulo: ';
            app.ValorAngulo_2 = uilabel(app.EstadoTab);
            app.ValorAngulo_2.Text = ' 0.00 ';
            app.oLabel_2 = uilabel(app.EstadoTab);
            app.oLabel_2.Text = 'o';
            app.AnguloLabel_2 = uilabel(app.EstadoTab);
            app.AnguloLabel_2.Text = {'Velocidad'; 'Angular:'};
            app.sLabel = uilabel(app.EstadoTab);
            app.sLabel.Text = '/s';
            app.UIAxes2 = uiaxes(app.EstadoTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.GyroscopioLabel = uilabel(app.EstadoTab);
            app.GyroscopioLabel.Text = 'Gyroscopio';
            app.AcelermetroLabel = uilabel(app.EstadoTab);
            app.AcelermetroLabel.Text = 'Acelerómetro';
            app.XLabel = uilabel(app.EstadoTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.EstadoTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.EstadoTab);
            app.ZLabel.Text = 'Z';
            app.Gx = uilabel(app.EstadoTab);
            app.Gx.Text = '0000';
            app.Gy = uilabel(app.EstadoTab);
            app.Gy.Text = '0000';
            app.Gz = uilabel(app.EstadoTab);
            app.Gz.Text = '0000';
            app.Ax = uilabel(app.EstadoTab);
            app.Ax.Text = '0000';
            app.Ay = uilabel(app.EstadoTab);
            app.Ay.Text = '0000';
            app.Az = uilabel(app.EstadoTab);
            app.Az.Text = '0000';
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.DatosLabel = uilabel(app.DatosTab);
            app.DatosLabel.Text = 'Datos';
            app.RegresarButton_2 = uibutton(app.DatosTab, 'push');
            app.RegresarButton_2.ButtonPushedFcn = createCallbackFcn(app, @RegresarButton_2Pushed, true);
            app.RegresarButton_2.Text = 'Regresar';
            app.UIAxes = uiaxes(app.DatosTab);
            title(app.UIAxes, 'Angulo')
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Angulo')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-90 90];
            app.DatosagraficarSliderLabel = uilabel(app.DatosTab);
            app.DatosagraficarSliderLabel.Text = {'Datos a'; 'graficar'};
            app.N = uislider(app.DatosTab);
            app.N.Limits = [5 100];
            app.N.ValueChangedFcn = createCallbackFcn(app, @NValueChanged, true);
            app.N.Value = 5;
            app.reset = uibutton(app.DatosTab, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'RESET';
            app.ParametrosTab = uitab(app.TabGroup);
            app.ParametrosTab.Title = 'Parametros';
            app.ParametrosdelSistemaLabel = uilabel(app.ParametrosTab);
            app.ParametrosdelSistemaLabel.Text = {'Parametros del '; 'Sistema '};
            app.RegresarButton_3 = uibutton(app.ParametrosTab, 'push');
            app.RegresarButton_3.Text = 'Regresar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_Grafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end